classdef Confirmation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Table                           matlab.ui.control.Table
        YesButton                       matlab.ui.control.Button
        NoButton                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        DoyouwanttosavethisresultLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp % Description
        newapp

        M;
        L;
        fba;
        fcb;
        ax;
        fcd;
        ex;
        fed;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Primary, Masses, Lengths, Fba, Fcb, Ax, Fcd, Ex, Fed)
            app.callingapp = Primary;
            app.M = Masses;
            app.L = Lengths;
            app.fba = Fba;
            app.fcb = Fcb;
            app.ax = Ax;
            app.fcd = Fcd;
            app.ex = Ex;
            app.fed = Fed;
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.newapp = Secondary(app,app.M,app.L,app.fba,app.fcb,app.ax,app.fcd,app.ex,app.fed);
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            m1 = app.M 
            l1 = app.L
            fbaa = app.fba
            fcbb = app.fcb
            axx = app.ax
            fcdd = app.fcd
            exx = app.ex
            fedd = app.fed
            
            [file,path] = uiputfile('myMatrix.mat','Save file name');
            fullFileName = fullfile(path, file);
            save(fullFileName,'m1','l1','fbaa','fcbb','axx','fcdd','exx','fedd');
                        
            app.Table = uitable('Data', [m1 ,l1 ,axx , fcbb ,fbaa ,fcdd ,exx, fedd], 'ColumnFormat', {'numeric'});
            app.Table.ColumnName = {'Masses' , 'Lengths', 'Ax', 'Fcb', 'Fba', 'Fcd','Ex','Fed'};
            app.Table.Position = [6 139 638 154]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoyouwanttosavethisresultLabel = uilabel(app.UIFigure);
            app.DoyouwanttosavethisresultLabel.Text = 'Do you want to save this result ?';
            app.Button = uibutton(app.UIFigure, 'push');
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.Table = uitable(app.UIFigure);
            app.Table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Confirmation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end