classdef Primary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        QuestionButton      matlab.ui.control.Button
        ResetButton         matlab.ui.control.Button
        CalculateButton     matlab.ui.control.Button
        FBDButton           matlab.ui.control.Button
        Length_Increment    matlab.ui.control.NumericEditField
        Mass_Increment      matlab.ui.control.NumericEditField
        Length_Minimum      matlab.ui.control.NumericEditField
        Length_Maximum      matlab.ui.control.NumericEditField
        Mass_Maximum        matlab.ui.control.NumericEditField
        Mass_Minimum        matlab.ui.control.NumericEditField
        IncrementLabel      matlab.ui.control.Label
        MaximumLabel        matlab.ui.control.Label
        MinimumLabel        matlab.ui.control.Label
        LengthLabel         matlab.ui.control.Label
        MassLabel           matlab.ui.control.Label
        TrussAnalysisLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        newapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Ax, Fcd, Fba, Fcb, Masses, Lengths)
            %app.newapp = Confirmation;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Length_Minimum.Value = 0;
            app.Length_Maximum.Value = 0;
            app.Length_Increment.Value = 0;
            app.Mass_Maximum.Value = 0;
            app.Mass_Increment.Value = 0;
            app.Mass_Minimum.Value = 0;
        end

        % Button pushed function: FBDButton
        function FBDButtonPushed(app, event)
            app.newapp = FBD;
            %imshow("C:\Users\KAIF AHMED R\Desktop\FBD.png");
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            M = app.Mass_Maximum.Value;
            m = app.Mass_Minimum.Value;
            mi = app.Mass_Increment.Value;
            L = app.Length_Maximum.Value;
            l = app.Length_Minimum.Value;
            li = app.Length_Increment.Value;
            Masses = zeros(1,7)';
            Lengths = zeros(1,7)';
            Ax = zeros(1,7)';
            Fcd = zeros(1,7)';
            Fde = zeros(1,7)';
            Ex = zeros(1,7)';
            
            j=0;

            for j = 1:10
                if m<=M || l<=L
                    
                    Masses(j) =  m
                    Lengths(j) = l 
                    
                    Ax(j) = -13.37*m/1000
                    Fcd(j) = -16.69*m/1000
                    Fde(j) = 0.016*m
                    Ex(j) = 0.0128*m
                    m = m+mi;
                    l = l+li;
                    
                end
            end
            
            Fba = Ax
            Fcb = -1*Ax
            
            Masses
            Lengths
            
            app.newapp = Confirmation(app,Masses,Lengths,Fba,Fcb,Ax,Fcd,Ex,Fde);

            %app.newapp = Secondary(app,Masses,Lengths,Fba,Fcb,Ax,Fcd);
        end

        % Button pushed function: QuestionButton
        function QuestionButtonPushed(app, event)
            app.newapp = Question;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrussAnalysisLabel = uilabel(app.UIFigure);
            app.TrussAnalysisLabel.Text = 'Truss Analysis';
            app.MassLabel = uilabel(app.UIFigure);
            app.MassLabel.Text = 'Mass';
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Text = 'Length';
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Text = 'Minimum';
            app.MaximumLabel = uilabel(app.UIFigure);
            app.MaximumLabel.Text = 'Maximum';
            app.IncrementLabel = uilabel(app.UIFigure);
            app.IncrementLabel.Text = 'Increment';
            app.Mass_Minimum = uieditfield(app.UIFigure, 'numeric');
            app.Mass_Minimum.Value = 60;
            app.Mass_Maximum = uieditfield(app.UIFigure, 'numeric');
            app.Mass_Maximum.Value = 120;
            app.Length_Maximum = uieditfield(app.UIFigure, 'numeric');
            app.Length_Maximum.Value = 399.8;
            app.Length_Minimum = uieditfield(app.UIFigure, 'numeric');
            app.Length_Minimum.Value = 200;
            app.Mass_Increment = uieditfield(app.UIFigure, 'numeric');
            app.Mass_Increment.Value = 10;
            app.Length_Increment = uieditfield(app.UIFigure, 'numeric');
            app.Length_Increment.Value = 33.3;
            app.FBDButton = uibutton(app.UIFigure, 'push');
            app.FBDButton.ButtonPushedFcn = createCallbackFcn(app, @FBDButtonPushed, true);
            app.FBDButton.Text = 'FBD';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.QuestionButton = uibutton(app.UIFigure, 'push');
            app.QuestionButton.ButtonPushedFcn = createCallbackFcn(app, @QuestionButtonPushed, true);
            app.QuestionButton.Text = 'Question';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Primary(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end