classdef Secondary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GraphsLabel  matlab.ui.control.Label
        UITable      matlab.ui.control.Table
        UIAxes5      matlab.ui.control.UIAxes
        UIAxes4      matlab.ui.control.UIAxes
        UIAxes2_3    matlab.ui.control.UIAxes
        UIAxes2_2    matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
        Graph        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Confirmation, Masses, Lengths, Fba, Fcb, Ax, Fcd, Ex, Fed)
            app.callingapp = Confirmation;
            %app.callingapp = Primary;

            app.UITable= uitable('Data', [Masses ,Lengths ,Ax , Fcb ,Fba ,Fcd ,Ex, Fed], 'ColumnFormat', {'numeric'});
            app.UITable.ColumnName = {'Masses' , 'Lengths', 'Ax', 'Fcb', 'Fba', 'Fcd','Ex','Fed'};
            app.UITable.Position = [6 139 638 154];

            plot(app.Graph,Masses,Lengths)
            plot(app.UIAxes,Masses,Ax)
            plot(app.UIAxes2,Masses,Fba)
            plot(app.UIAxes2_2,Masses,Fcb)
            plot(app.UIAxes2_3,Masses,Fcd)
            plot(app.UIAxes4,Masses,Ex)
            plot(app.UIAxes5,Masses,Fed)

        end

        % Callback function
        function AxEditFieldValueChanged(app, event)

        end

        % Button down function: Graph
        function GraphButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph = uiaxes(app.UIFigure);
            title(app.Graph, 'Mass v/s Length')
            xlabel(app.Graph, 'Mass')
            ylabel(app.Graph, 'Length')
            zlabel(app.Graph, 'Z')
            app.Graph.ButtonDownFcn = createCallbackFcn(app, @GraphButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mass v/s Ax')
            xlabel(app.UIAxes, 'Mass')
            ylabel(app.UIAxes, 'Ax')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Mass v/s Fba')
            xlabel(app.UIAxes2, 'Mass')
            ylabel(app.UIAxes2, 'Fba')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Mass v/s Fcb')
            xlabel(app.UIAxes2_2, 'Mass')
            ylabel(app.UIAxes2_2, 'Fcb')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Mass v/s Fcd')
            xlabel(app.UIAxes2_3, 'Mass')
            ylabel(app.UIAxes2_3, 'Fcd')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Mass v/s Ex')
            xlabel(app.UIAxes4, 'Mass')
            ylabel(app.UIAxes4, 'Ex')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Mass v/s Fed')
            xlabel(app.UIAxes5, 'Mass')
            ylabel(app.UIAxes5, 'Fed')
            zlabel(app.UIAxes5, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.GraphsLabel = uilabel(app.UIFigure);
            app.GraphsLabel.Text = 'Graphs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Secondary(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end